classdef TimerComponent < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout   matlab.ui.container.GridLayout
        ResetButton  matlab.ui.control.Button
        StopButton   matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
        Label        matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        TimerEnded
    end

    properties (Access = private)
        ElapsedSeconds
        TimerObject
    end
    
    properties (Access = public)
        Minutes (1,1) double {mustBeNonnegative(Minutes), mustBeInteger(Minutes)} = 0;
        Seconds (1,1) double {mustBeNonnegative(Seconds), mustBeLessThanOrEqual(Seconds, 59), mustBeInteger(Seconds)} = 5;
    end
    
    
    methods (Access = private)
        
        function createTimerObj(comp)
            comp.TimerObject = timer;
            comp.TimerObject.Period = 1;
            comp.TimerObject.StartDelay = 1;
            comp.TimerObject.ExecutionMode = "fixedRate";
            comp.TimerObject.TimerFcn = @(~,~)updateDisplay(comp);
        
            comp.ElapsedSeconds = 0;
        end

        function updateDisplay(comp)
            comp.ElapsedSeconds = comp.ElapsedSeconds + 1;
            if comp.ElapsedSeconds >= comp.Seconds + 60*comp.Minutes
                stop(comp.TimerObject)
                comp.StartButton.Enable = "off";
                comp.StopButton.Enable = "off";
                notify(comp,"TimerEnded");
            end
            newTime = duration(0,comp.Minutes,comp.Seconds) - seconds(comp.ElapsedSeconds);
            newTime.Format = "mm:ss";
            comp.Label.Text = char(newTime);
        end
    end
    
    methods (Access = public)
        
        function delete(comp)
            stop(comp.TimerObject)
            delete(comp.TimerObject);
        end 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            createTimerObj(comp);
            time = duration(0,comp.Minutes,comp.Seconds);
            time.Format = "mm:ss";
            comp.Label.Text = char(time);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(comp, event)
            start(comp.TimerObject);
            comp.StartButton.Enable = "off";
        end

        % Button pushed function: StopButton
        function StopButtonPushed(comp, event)
            stop(comp.TimerObject);
            comp.StartButton.Enable = "on";
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(comp, event)
            stop(comp.TimerObject);
            delete(comp.TimerObject);
            createTimerObj(comp);
            time = duration(0,comp.Minutes,comp.Seconds);
            time.Format = "mm:ss";
            comp.Label.Text = char(time);
            comp.StartButton.Enable = "on";
            comp.StopButton.Enable = "on";
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            newTime = duration(0,comp.Minutes,comp.Seconds) - seconds(comp.ElapsedSeconds);
            newTime.Format = "mm:ss";
            comp.Label.Text = char(newTime);
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 219 98];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.Label = uilabel(comp.GridLayout);
            comp.Label.Text = '00:05';
            comp.StartButton = uibutton(comp.GridLayout, 'push');
            comp.StartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StartButtonPushed, true);
            comp.StartButton.Text = 'Start';
            comp.StopButton = uibutton(comp.GridLayout, 'push');
            comp.StopButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @StopButtonPushed, true);
            comp.StopButton.Text = 'Stop';
            comp.ResetButton = uibutton(comp.GridLayout, 'push');
            comp.ResetButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ResetButtonPushed, true);
            comp.ResetButton.Text = 'Reset';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end