classdef CirclevsSphere < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CirclevsSphereUIFigure       matlab.ui.Figure
        SpherePanel                  matlab.ui.container.Panel
        SurfaceEditField             matlab.ui.control.NumericEditField
        SurfaceEditFieldLabel        matlab.ui.control.Label
        VolumeEditField              matlab.ui.control.NumericEditField
        VolumeEditFieldLabel         matlab.ui.control.Label
        Image_2                      matlab.ui.control.Image
        CirclePanel                  matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
        CircumferenceEditField       matlab.ui.control.NumericEditField
        CircumferenceEditFieldLabel  matlab.ui.control.Label
        AreaEditField                matlab.ui.control.NumericEditField
        AreaLabel                    matlab.ui.control.Label
        TheDiameterEditField         matlab.ui.control.NumericEditField
        TheDiameterLabel             matlab.ui.control.Label
        EnterTheRadiusEditField      matlab.ui.control.NumericEditField
        TheRadiusLabel               matlab.ui.control.Label
        CirclevsSphereLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EnterTheRadiusEditField
        function EnterTheRadiusEditFieldValueChanged(app, event)
            RadiusValue = app.EnterTheRadiusEditField.Value;
            app.TheDiameterEditField.Value = 2 * RadiusValue;

            CircleArea = pi * RadiusValue * RadiusValue;
            Circumference = 2 * pi * RadiusValue;

            SphereVolume  = (4/3) * pi * RadiusValue^3;
            SphereSurfaceArea = 4 * pi * RadiusValue^2;

            app.AreaEditField.Value = CircleArea;
            app.CircumferenceEditField.Value = Circumference;
            app.VolumeEditField.Value = SphereVolume;
            app.SurfaceEditField.Value = SphereSurfaceArea;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CirclevsSphereUIFigure = uifigure('Visible', 'off');
            app.CirclevsSphereUIFigure.Name = 'Circle vs Sphere';
            app.CirclevsSphereLabel = uilabel(app.CirclevsSphereUIFigure);
            app.CirclevsSphereLabel.Text = 'Circle vs Sphere';
            app.TheRadiusLabel = uilabel(app.CirclevsSphereUIFigure);
            app.TheRadiusLabel.Text = 'Enter The Radius:';
            app.EnterTheRadiusEditField = uieditfield(app.CirclevsSphereUIFigure, 'numeric');
            app.EnterTheRadiusEditField.Limits = [0 Inf];
            app.EnterTheRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @EnterTheRadiusEditFieldValueChanged, true);
            app.TheDiameterLabel = uilabel(app.CirclevsSphereUIFigure);
            app.TheDiameterLabel.Text = 'The Diameter:';
            app.TheDiameterEditField = uieditfield(app.CirclevsSphereUIFigure, 'numeric');
            app.TheDiameterEditField.Editable = 'off';
            app.CirclePanel = uipanel(app.CirclevsSphereUIFigure);
            app.CirclePanel.Title = 'Circle';
            app.AreaLabel = uilabel(app.CirclePanel);
            app.AreaLabel.Text = 'Area:';
            app.AreaEditField = uieditfield(app.CirclePanel, 'numeric');
            app.AreaEditField.Editable = 'off';
            app.CircumferenceEditFieldLabel = uilabel(app.CirclePanel);
            app.CircumferenceEditFieldLabel.Text = 'Circumference:';
            app.CircumferenceEditField = uieditfield(app.CirclePanel, 'numeric');
            app.CircumferenceEditField.Editable = 'off';
            app.Image = uiimage(app.CirclePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Circle.png');
            app.SpherePanel = uipanel(app.CirclevsSphereUIFigure);
            app.SpherePanel.Title = 'Sphere';
            app.Image_2 = uiimage(app.SpherePanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Sphere.png');
            app.VolumeEditFieldLabel = uilabel(app.SpherePanel);
            app.VolumeEditFieldLabel.Text = 'Volume:';
            app.VolumeEditField = uieditfield(app.SpherePanel, 'numeric');
            app.VolumeEditField.Editable = 'off';
            app.SurfaceEditFieldLabel = uilabel(app.SpherePanel);
            app.SurfaceEditFieldLabel.Text = 'Surface:';
            app.SurfaceEditField = uieditfield(app.SpherePanel, 'numeric');
            app.SurfaceEditField.Editable = 'off';
            % Show the figure after all components are created
            app.CirclevsSphereUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CirclevsSphere

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CirclevsSphereUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CirclevsSphereUIFigure)
        end
    end
end