classdef Quadratic_Equation_Solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        X_2EditField_2                matlab.ui.control.EditField
        X_2EditField_2Label           matlab.ui.control.Label
        X_1EditField_2                matlab.ui.control.EditField
        X_1EditField_2Label           matlab.ui.control.Label
        SolveForXButton               matlab.ui.control.Button
        EditField                     matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        XEditFieldLabel               matlab.ui.control.Label
        X2EditField                   matlab.ui.control.NumericEditField
        X2Label_2                     matlab.ui.control.Label
        QuadraticEquationSolverLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            a = app.X2EditField.Value;
            b = app.XEditField.Value;
            c = app.EditField.Value;

            discriminant = (b^2) - (4*a*c);
            x1 = (-b + sqrt(discriminant)) / (2 * a);
            x2 = (-b - sqrt(discriminant)) / (2 * a);

            app.X_1EditField_2.Value = num2str(x1);
            app.X_2EditField_2.Value = num2str(x2);
        end

        % Button pushed function: SolveForXButton
        function SolveForXButtonPushed(app, event)
            a = app.X2EditField.Value;
            b = app.XEditField.Value;
            c = app.EditField.Value;

            discriminant = (b^2) - (4*a*c);
            x1 = (-b + sqrt(discriminant)) / (2 * a);
            x2 = (-b - sqrt(discriminant)) / (2 * a);

            app.X_1EditField_2.Value = num2str(x1);
            app.X_2EditField_2.Value = num2str(x2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.QuadraticEquationSolverLabel = uilabel(app.UIFigure);
            app.QuadraticEquationSolverLabel.Text = 'Quadratic Equation Solver';
            app.X2Label_2 = uilabel(app.UIFigure);
            app.X2Label_2.Interpreter = 'tex';
            app.X2Label_2.Text = 'X^2 +';
            app.X2EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditField.Value = 1;
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Interpreter = 'tex';
            app.XEditFieldLabel.Text = 'X +';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Value = 1;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Interpreter = 'tex';
            app.EditFieldLabel.Text = '= 0';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 1;
            app.SolveForXButton = uibutton(app.UIFigure, 'push');
            app.SolveForXButton.ButtonPushedFcn = createCallbackFcn(app, @SolveForXButtonPushed, true);
            app.SolveForXButton.Text = 'Solve For X';
            app.X_1EditField_2Label = uilabel(app.UIFigure);
            app.X_1EditField_2Label.Interpreter = 'tex';
            app.X_1EditField_2Label.Text = 'X_1 = ';
            app.X_1EditField_2 = uieditfield(app.UIFigure, 'text');
            app.X_1EditField_2.Editable = 'off';
            app.X_2EditField_2Label = uilabel(app.UIFigure);
            app.X_2EditField_2Label.Interpreter = 'tex';
            app.X_2EditField_2Label.Text = 'X_2 = ';
            app.X_2EditField_2 = uieditfield(app.UIFigure, 'text');
            app.X_2EditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Quadratic_Equation_Solver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end