classdef Speed_Converter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpeedConverterUIFigure      matlab.ui.Figure
        mphGauge                    matlab.ui.control.SemicircularGauge
        mphGaugeLabel               matlab.ui.control.Label
        KmhrGauge                   matlab.ui.control.SemicircularGauge
        KmhrGaugeLabel              matlab.ui.control.Label
        SpeedSpinner                matlab.ui.control.Spinner
        SpeedSpinnerLabel           matlab.ui.control.Label
        SpeedSpinner_2              matlab.ui.control.Spinner
        SpeedSpinner_2Label         matlab.ui.control.Label
        MileperhourLabel            matlab.ui.control.Label
        KilometreperhourLabel       matlab.ui.control.Label
        SpeedConverterKmhrmphLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function SpeedEditFieldValueChanged(app, event)
           

        end

        % Callback function
        function SpeedEditField_2ValueChanged(app, event)
            
            
        end

        % Value changed function: SpeedSpinner_2
        function SpeedSpinner_2ValueChanged(app, event)
             M_HR_value = app.SpeedSpinner_2.Value;
            app.mphGauge.Value = M_HR_value;
            app.SpeedSpinner.Value = M_HR_value * 1.609344;
            app.KmhrGauge.Value = app.SpeedSpinner.Value;
            
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
             KM_HR_value = app.SpeedSpinner.Value;
            app.KmhrGauge.Value = KM_HR_value;
            app.SpeedSpinner_2.Value = KM_HR_value * 0.6213711922;
            app.mphGauge.Value = app.SpeedSpinner_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.SpeedConverterUIFigure = uifigure('Visible', 'off');
            app.SpeedConverterUIFigure.Name = 'Speed Converter';
            app.SpeedConverterUIFigure.Icon = fullfile(pathToMLAPP, 'speed.png');
            app.SpeedConverterKmhrmphLabel = uilabel(app.SpeedConverterUIFigure);
            app.SpeedConverterKmhrmphLabel.Text = {'Speed Converter'; ' Km/hr ⇆ mph'};
            app.KilometreperhourLabel = uilabel(app.SpeedConverterUIFigure);
            app.KilometreperhourLabel.Text = 'Kilometre per hour';
            app.MileperhourLabel = uilabel(app.SpeedConverterUIFigure);
            app.MileperhourLabel.Text = 'Mile per hour';
            app.SpeedSpinner_2Label = uilabel(app.SpeedConverterUIFigure);
            app.SpeedSpinner_2Label.Text = 'Speed';
            app.SpeedSpinner_2 = uispinner(app.SpeedConverterUIFigure);
            app.SpeedSpinner_2.Limits = [0 Inf];
            app.SpeedSpinner_2.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinner_2ValueChanged, true);
            app.SpeedSpinnerLabel = uilabel(app.SpeedConverterUIFigure);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.SpeedConverterUIFigure);
            app.SpeedSpinner.Limits = [0 Inf];
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.KmhrGaugeLabel = uilabel(app.SpeedConverterUIFigure);
            app.KmhrGaugeLabel.Text = 'Km/hr';
            app.KmhrGauge = uigauge(app.SpeedConverterUIFigure, 'semicircular');
            app.KmhrGauge.Limits = [0 330];
            app.mphGaugeLabel = uilabel(app.SpeedConverterUIFigure);
            app.mphGaugeLabel.Text = 'mph';
            app.mphGauge = uigauge(app.SpeedConverterUIFigure, 'semicircular');
            app.mphGauge.Limits = [0 205];
            % Show the figure after all components are created
            app.SpeedConverterUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Speed_Converter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpeedConverterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpeedConverterUIFigure)
        end
    end
end