classdef Temperature_Unit_Converter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OutputTempretureEditField       matlab.ui.control.NumericEditField
        OutputTempretureEditFieldLabel  matlab.ui.control.Label
        InputTempretureEditField        matlab.ui.control.NumericEditField
        InputTempretureEditFieldLabel   matlab.ui.control.Label
        ToListBox                       matlab.ui.control.ListBox
        ToListBoxLabel                  matlab.ui.control.Label
        FromListBox                     matlab.ui.control.ListBox
        FromListBoxLabel                matlab.ui.control.Label
        TemperatureUnitConverterLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Tin = 32;
        Tout = 32;
        Type1 = "Celsius";
        Type2 = "Celsius"
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputTempretureEditField
        function InputTempretureEditFieldValueChanged(app, event)
            inputValue = app.InputTempretureEditField.Value;
            app.Tin = inputValue;
            app.FromListBoxValueChanged();
        end

        % Value changed function: FromListBox
        function FromListBoxValueChanged(app, event)
            app.Type1 = app.FromListBox.Value;
            app.Type2 = app.ToListBox.Value;

            if strcmp(app.Type1,"Celsius") ==1
                   if strcmp(app.Type2,"Celsius")==1
                       app.Tout = app.Tin;
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Fahrenheit")==1
                       app.Tout = (app.Tin * (9/5) + 32);
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Kelvin")==1
                       app.Tout = app.Tin + 273.15;
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Rankine")==1
                       app.Tout = (app.Tin *(9/5) + 491.67);
                       app.OutputTempretureEditField.Value = app.Tout;
                   end
            elseif strcmp(app.Type1,"Fahrenheit")==1
                if strcmp(app.Type2,"Celsius")==1
                       app.Tout = (app.Tin - 32) * (5/9);
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Fahrenheit")==1
                       app.Tout = app.Tin;
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Kelvin")==1
                       app.Tout = (app.Tin - 32) * (5/9) + 273.15;
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Rankine")==1
                       app.Tout = app.Tin + 459.67 ;
                       app.OutputTempretureEditField.Value = app.Tout;
                end
            elseif strcmp(app.Type1,"Kelvin")==1
                if strcmp(app.Type2,"Celsius")==1
                       app.Tout = (app.Tin - 273.15);
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Fahrenheit")==1
                       app.Tout = (app.Tin - 273.15) * (9/5) + 32;
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Kelvin")==1
                       app.Tout = app.Tin;
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Rankine")==1
                       app.Tout = app.Tin * 1.8 ;
                       app.OutputTempretureEditField.Value = app.Tout;
                end
            elseif strcmp(app.Type1,"Rankine")==1
                if strcmp(app.Type2,"Celsius")==1
                       app.Tout = (app.Tin - 491.67) * (5/9);
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Fahrenheit")==1
                       app.Tout = (app.Tin - 459.67 );
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Kelvin")==1
                       app.Tout = app.Tin * 5/9;
                       app.OutputTempretureEditField.Value = app.Tout;
                   elseif strcmp(app.Type2,"Rankine")==1
                       app.Tout = app.Tin;
                       app.OutputTempretureEditField.Value = app.Tout;
                end
            end
        end

        % Value changed function: ToListBox
        function ToListBoxValueChanged(app, event)
            app.FromListBoxValueChanged();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TemperatureUnitConverterLabel = uilabel(app.UIFigure);
            app.TemperatureUnitConverterLabel.Text = 'Temperature Unit Converter';
            app.FromListBoxLabel = uilabel(app.UIFigure);
            app.FromListBoxLabel.Text = 'From:';
            app.FromListBox = uilistbox(app.UIFigure);
            app.FromListBox.Items = {'Celsius', 'Fahrenheit', 'Kelvin', 'Rankine'};
            app.FromListBox.ValueChangedFcn = createCallbackFcn(app, @FromListBoxValueChanged, true);
            app.FromListBox.Value = 'Celsius';
            app.ToListBoxLabel = uilabel(app.UIFigure);
            app.ToListBoxLabel.Text = 'To:';
            app.ToListBox = uilistbox(app.UIFigure);
            app.ToListBox.Items = {'Celsius', 'Fahrenheit', 'Kelvin', 'Rankine'};
            app.ToListBox.ValueChangedFcn = createCallbackFcn(app, @ToListBoxValueChanged, true);
            app.ToListBox.Value = 'Celsius';
            app.InputTempretureEditFieldLabel = uilabel(app.UIFigure);
            app.InputTempretureEditFieldLabel.Text = 'Input Tempreture';
            app.InputTempretureEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputTempretureEditField.ValueChangedFcn = createCallbackFcn(app, @InputTempretureEditFieldValueChanged, true);
            app.OutputTempretureEditFieldLabel = uilabel(app.UIFigure);
            app.OutputTempretureEditFieldLabel.Text = 'Output Tempreture';
            app.OutputTempretureEditField = uieditfield(app.UIFigure, 'numeric');
            app.OutputTempretureEditField.Editable = 'off';
            app.OutputTempretureEditField.Tooltip = {''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Temperature_Unit_Converter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end