classdef Visualizing_Square_Rectangle_Area < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SquareTab                    matlab.ui.container.Tab
        SquareAreaEditField          matlab.ui.control.NumericEditField
        SquareAreaEditFieldLabel     matlab.ui.control.Label
        SquareMemberLengthEditField  matlab.ui.control.NumericEditField
        SquareMemberLengthEditFieldLabel  matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        RectangleTab                 matlab.ui.container.Tab
        HeightEditField              matlab.ui.control.NumericEditField
        HeightEditFieldLabel         matlab.ui.control.Label
        RectangleAreaEditField       matlab.ui.control.NumericEditField
        RectangleAreaEditFieldLabel  matlab.ui.control.Label
        WidthEditField               matlab.ui.control.NumericEditField
        WidthEditFieldLabel          matlab.ui.control.Label
        EditField_2                  matlab.ui.control.EditField
        VisualizingSquareRectangleAreaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SquareMemberLengthEditField
        function SquareMemberLengthEditFieldValueChanged(app, event)
            Sqm = app.SquareMemberLengthEditField.Value;
            app.EditField.Position(3) = Sqm;
            app.EditField.Position(4) = Sqm;
            app.SquareAreaEditField.Value = Sqm * Sqm;
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            Rqw = app.WidthEditField.Value;
            Rqh = app.HeightEditField.Value;

            app.EditField_2.Position(3) = Rqw;
            app.EditField_2.Position(4) = Rqh;

            app.RectangleAreaEditField.Value = Rqw * Rqh;
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            Rqw = app.WidthEditField.Value;
            Rqh = app.HeightEditField.Value;

            app.EditField_2.Position(3) = Rqw;
            app.EditField_2.Position(4) = Rqh;

            app.RectangleAreaEditField.Value = Rqw * Rqh;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VisualizingSquareRectangleAreaLabel = uilabel(app.UIFigure);
            app.VisualizingSquareRectangleAreaLabel.Text = 'Visualizing Square & Rectangle Area';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SquareTab = uitab(app.TabGroup);
            app.SquareTab.Title = 'Square';
            app.EditField = uieditfield(app.SquareTab, 'text');
            app.SquareMemberLengthEditFieldLabel = uilabel(app.SquareTab);
            app.SquareMemberLengthEditFieldLabel.Text = 'Square Member Length';
            app.SquareMemberLengthEditField = uieditfield(app.SquareTab, 'numeric');
            app.SquareMemberLengthEditField.ValueChangedFcn = createCallbackFcn(app, @SquareMemberLengthEditFieldValueChanged, true);
            app.SquareMemberLengthEditField.Value = 22;
            app.SquareAreaEditFieldLabel = uilabel(app.SquareTab);
            app.SquareAreaEditFieldLabel.Text = 'Square Area';
            app.SquareAreaEditField = uieditfield(app.SquareTab, 'numeric');
            app.SquareAreaEditField.Value = 484;
            app.RectangleTab = uitab(app.TabGroup);
            app.RectangleTab.Title = 'Rectangle';
            app.EditField_2 = uieditfield(app.RectangleTab, 'text');
            app.WidthEditFieldLabel = uilabel(app.RectangleTab);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.RectangleTab, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Value = 22;
            app.RectangleAreaEditFieldLabel = uilabel(app.RectangleTab);
            app.RectangleAreaEditFieldLabel.Text = 'Rectangle Area';
            app.RectangleAreaEditField = uieditfield(app.RectangleTab, 'numeric');
            app.RectangleAreaEditField.Value = 484;
            app.HeightEditFieldLabel = uilabel(app.RectangleTab);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.RectangleTab, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 22;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualizing_Square_Rectangle_Area

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end