classdef Visualizing_Square_Area < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EditField                    matlab.ui.control.EditField
        AreaOfSquareEditField        matlab.ui.control.NumericEditField
        AreaOfSquareEditFieldLabel   matlab.ui.control.Label
        VisualizingSquareAreaLabel   matlab.ui.control.Label
        SquareMemberLengthEditField  matlab.ui.control.NumericEditField
        SquareMemberLengthEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        defulatM = 22; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SquareMemberLengthEditField.Value = app.defulatM;
            app.AreaOfSquareEditField.Value = app.defulatM*app.defulatM;
        end

        % Value changed function: SquareMemberLengthEditField
        function SquareMemberLengthEditFieldValueChanged(app, event)
            SqMLength = app.SquareMemberLengthEditField.Value;
            app.AreaOfSquareEditField.Value = SqMLength*SqMLength;
            app.EditField.Position(3) = SqMLength;
            app.EditField.Position(4) = SqMLength;

            app.EditField.Position(1) = 310 - 0.5 * SqMLength;
            app.EditField.Position(2) = 230 - 0.5 * SqMLength;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SquareMemberLengthEditFieldLabel = uilabel(app.UIFigure);
            app.SquareMemberLengthEditFieldLabel.Text = 'Square Member Length';
            app.SquareMemberLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SquareMemberLengthEditField.ValueChangedFcn = createCallbackFcn(app, @SquareMemberLengthEditFieldValueChanged, true);
            app.VisualizingSquareAreaLabel = uilabel(app.UIFigure);
            app.VisualizingSquareAreaLabel.Text = 'Visualizing Square Area';
            app.AreaOfSquareEditFieldLabel = uilabel(app.UIFigure);
            app.AreaOfSquareEditFieldLabel.Text = 'Area Of Square';
            app.AreaOfSquareEditField = uieditfield(app.UIFigure, 'numeric');
            app.AreaOfSquareEditField.Editable = 'off';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualizing_Square_Area

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end